classdef hwfw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        APUUNIVERSITYLabel            matlab.ui.control.Label
        POWERCONVERTERCIRCUITSIMULATIONLabel  matlab.ui.control.Label
        CIRCUITDropDownLabel          matlab.ui.control.Label
        CIRCUITDropDown               matlab.ui.control.DropDown
        SelectTypeofrectifierCircuitLabel  matlab.ui.control.Label
        EnterInputVoltageandFrequencyLabel  matlab.ui.control.Label
        VoltageEditFieldLabel         matlab.ui.control.Label
        VoltageEditField              matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel       matlab.ui.control.Label
        FrequencyEditField            matlab.ui.control.NumericEditField
        CapacitoreffectSwitchLabel    matlab.ui.control.Label
        CapacitoreffectSwitch         matlab.ui.control.RockerSwitch
        InputAxes                     matlab.ui.control.UIAxes
        OutputAxes                    matlab.ui.control.UIAxes
        ResistorValueEditFieldLabel   matlab.ui.control.Label
        ResistorValueEditField        matlab.ui.control.NumericEditField
        CapacitorValueEditFieldLabel  matlab.ui.control.Label
        CapacitorValueEditField       matlab.ui.control.NumericEditField
        ComparisonAxes                matlab.ui.control.UIAxes
        SimulationSwitchLabel         matlab.ui.control.Label
        SimulationSwitch              matlab.ui.control.Switch
        VoltagePeaktoPeakEditFieldLabel  matlab.ui.control.Label
        VoltagePeaktoPeakEditField    matlab.ui.control.NumericEditField
        OHMLabel                      matlab.ui.control.Label
        uVLabel                       matlab.ui.control.Label
    end

    
    properties (Access = private)
       voltage_input_amp = 10; %input voltage amplitude
       Input_signal;
       frequency = 50; %default frequency
       resistance = 1000;%default resistance
       capacitor = 1200e-6; %default capacitance
       voltage_output;%output voltage variable
       voltage_rippleptp;%ripple voltage
       omega;
       % below are the time variables
       t;
       t1;
       t2;
       timevalue;
       mainstate=0;%hold the state 
       dropdown= 0;%for the type of rectifier
       capcaitorfilterselection=0;  
       v;    % Description
    end
    
    methods (Access = private)
        
        function main(app,state)% main function requiers the state paramenter for exectuib
           if state == 1
               app.t = (0:1/1000:0.1);%timpe variable 
               app.omega = 2 * pi * app.frequency;%omega calculation 
               app.Input_signal = app.voltage_input_amp * sin(app.omega *app.t);%input signal 
               plot(app.InputAxes, app.Input_signal)% plotting the input signal in the first axis
               tau = app.resistance * app.capacitor; % tau parameter for capacitor filter
               
           
                for l = 1:100 %program will run from 1 to 100 with an incremnt of 1 so total of 100 samples 
                    if app.dropdown == 1% half wave recitification
                       %ripple voltage formula implementaiton for half wave    
                        app.VoltagePeaktoPeakEditField.Value = (app.voltage_input_amp)/(app.frequency * app.resistance *app.capacitor);
               
                            app.t2 = 24;
                            %output voltage calculation based on the
                            %conditons
                            if sin(app.omega*app.t(l)) <=0
                                app.voltage_output(l) = 0;
                            else
                                app.voltage_output(l) = app.voltage_input_amp * sin(app.omega *app.t(l));
                            end
                    
                            
                    else 
                        %ripple voltage formula implementation for full
                        %wave
                        app.VoltagePeaktoPeakEditField.Value = (app.voltage_input_amp)/(app.frequency * 2 * app.resistance *app.capacitor);
               
                        app.t2 = 14;
                        %conditonal execution for output
                         if sin(app.omega*app.t(l)) <=0
                            app.voltage_output(l) = -app.voltage_input_amp * sin(app.omega *app.t(l));
                        else
                            app.voltage_output(l) = app.voltage_input_amp * sin(app.omega *app.t(l));
                         end
                     
                    end
                
                end
                
                app.timevalue = 1;%state variable for three case
                if(app.capcaitorfilterselection == 1)
                    
                    app.t1=6;
                    for l=1:100
                        %switch case for all the three phases of rectifier
                        %output
                        switch app.timevalue
                            case 1
                                app.v(l) = app.voltage_output(l);
                                if app.v(l) == app.voltage_input_amp
                                    app.timevalue =2;
                                end
                            case 2
                                app.v(l) = app.voltage_input_amp * (exp(-(app.t(app.t1))/tau));
                                  if(app.t1 == app.t2)
                                    app.timevalue = 3;
                                  end
                                    app.t1= app.t1+1;
                            case 3
                                app.v(l) = app.voltage_output(l);
                                if(app.v(l) == app.voltage_input_amp)
                                     
                                    app.timevalue = 2;
                                    app.t1=6; 
                                end
                        end
                    
                
                       % app.voltage_output(l) = v(l);
                    
                    end
                
                end
                
                
                %plotting all the axis
                if app.capcaitorfilterselection == 1
                    plot(app.OutputAxes, app.v)
                else
                    plot(app.OutputAxes, app.voltage_output)
                end
           
                plot(app.ComparisonAxes, app.voltage_output)
                hold(app.ComparisonAxes, "on")
                plot(app.ComparisonAxes, app.v)
                hold(app.ComparisonAxes,"off")
           else 
                
           end
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CIRCUITDropDown
        function CIRCUITDropDownValueChanged(app, event)
          % this function is for the selection of the type of rectigier
            value = app.CIRCUITDropDown.Value;
            if value == "HALF WAVE"
               app.dropdown = 1;
            else
                app.dropdown = 0;
            end
            
              main(app,app.mainstate)
        end

        % Value changed function: SimulationSwitch
        function SimulationSwitchValueChanged(app, event)
            %this function is for the simulation execution 
            value = app.SimulationSwitch.Value;
            
            if value == "On"
                app.mainstate = 1;
                main(app,app.mainstate)
            else
                app.mainstate = 0;
                main(app,app.mainstate)
            end
       
        end

        % Value changed function: VoltageEditField
        function VoltageEditFieldValueChanged(app, event)
           %this function holds the user voltage function
            value = app.VoltageEditField.Value;
             if value <=0
                 errordlg('ENTER A POSITIVE VALUE','INPUT ERROR');
                 
             else  
                app.voltage_input_amp = value;
             end
            main(app,app.mainstate)
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            if value <=0
                 errordlg('ENTER A POSITIVE VALUE','INPUT ERROR');
                 
             else 
                 app.frequency = value;
            end
            main(app, app.mainstate)
        end

        % Value changed function: CapacitoreffectSwitch
        function CapacitoreffectSwitchValueChanged(app, event)
            value = app.CapacitoreffectSwitch.Value;
            if value == "On"
               app.capcaitorfilterselection = 1;
            else
                app.capcaitorfilterselection = 0;
               
               
            end
            main(app, app.mainstate)
        end

        % Value changed function: VoltagePeaktoPeakEditField
        function VoltagePeaktoPeakEditFieldValueChanged(app, event)
          
            
        end

        % Value changed function: ResistorValueEditField
        function ResistorValueEditFieldValueChanged(app, event)
            value = app.ResistorValueEditField.Value;
             if value <=0
                 errordlg('Resistance cant be negative or Zero','INPUT ERROR');
                 
             else 
                 app.resistance = value;
            end
            main(app, app.mainstate)
        end

        % Value changed function: CapacitorValueEditField
        function CapacitorValueEditFieldValueChanged(app, event)
            value = app.CapacitorValueEditField.Value;
            if value <=0
                 errordlg('Please enter a positive value','INPUT ERROR');
                 
            else 
                 app.capacitor = value * 10^-6;
            end
            main(app, app.mainstate)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.APUUNIVERSITYLabel = uilabel(app.UIFigure);
            app.APUUNIVERSITYLabel.Text = 'APU UNIVERSITY';
            app.POWERCONVERTERCIRCUITSIMULATIONLabel = uilabel(app.UIFigure);
            app.POWERCONVERTERCIRCUITSIMULATIONLabel.Text = 'POWER CONVERTER CIRCUIT SIMULATION';
            app.CIRCUITDropDownLabel = uilabel(app.UIFigure);
            app.CIRCUITDropDownLabel.Text = 'CIRCUIT';
            app.CIRCUITDropDown = uidropdown(app.UIFigure);
            app.CIRCUITDropDown.Items = {'FULL WAVE', 'HALF WAVE', ''};
            app.CIRCUITDropDown.ValueChangedFcn = createCallbackFcn(app, @CIRCUITDropDownValueChanged, true);
            app.CIRCUITDropDown.Value = 'FULL WAVE';
            app.SelectTypeofrectifierCircuitLabel = uilabel(app.UIFigure);
            app.SelectTypeofrectifierCircuitLabel.Text = 'Select Type of rectifier Circuit';
            app.EnterInputVoltageandFrequencyLabel = uilabel(app.UIFigure);
            app.EnterInputVoltageandFrequencyLabel.Text = 'Enter Input Voltage and Frequency';
            app.VoltageEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageEditFieldLabel.Text = 'Voltage';
            app.VoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltageEditField.ValueChangedFcn = createCallbackFcn(app, @VoltageEditFieldValueChanged, true);
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.CapacitoreffectSwitchLabel = uilabel(app.UIFigure);
            app.CapacitoreffectSwitchLabel.Text = 'Capacitor effect';
            app.CapacitoreffectSwitch = uiswitch(app.UIFigure, 'rocker');
            app.CapacitoreffectSwitch.ValueChangedFcn = createCallbackFcn(app, @CapacitoreffectSwitchValueChanged, true);
            app.InputAxes = uiaxes(app.UIFigure);
            title(app.InputAxes, 'Input')
            xlabel(app.InputAxes, 'time')
            ylabel(app.InputAxes, 'Voltage')
            app.OutputAxes = uiaxes(app.UIFigure);
            title(app.OutputAxes, 'Output')
            xlabel(app.OutputAxes, 'X')
            ylabel(app.OutputAxes, 'Voltage')
            app.ResistorValueEditFieldLabel = uilabel(app.UIFigure);
            app.ResistorValueEditFieldLabel.Text = 'Resistor Value';
            app.ResistorValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResistorValueEditField.ValueChangedFcn = createCallbackFcn(app, @ResistorValueEditFieldValueChanged, true);
            app.CapacitorValueEditFieldLabel = uilabel(app.UIFigure);
            app.CapacitorValueEditFieldLabel.Text = 'Capacitor Value';
            app.CapacitorValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.CapacitorValueEditField.ValueChangedFcn = createCallbackFcn(app, @CapacitorValueEditFieldValueChanged, true);
            app.ComparisonAxes = uiaxes(app.UIFigure);
            title(app.ComparisonAxes, 'Comparsion')
            xlabel(app.ComparisonAxes, 'X')
            ylabel(app.ComparisonAxes, 'Y')
            app.SimulationSwitchLabel = uilabel(app.UIFigure);
            app.SimulationSwitchLabel.Text = 'Simulation';
            app.SimulationSwitch = uiswitch(app.UIFigure, 'slider');
            app.SimulationSwitch.ValueChangedFcn = createCallbackFcn(app, @SimulationSwitchValueChanged, true);
            app.VoltagePeaktoPeakEditFieldLabel = uilabel(app.UIFigure);
            app.VoltagePeaktoPeakEditFieldLabel.Text = 'Voltage Peak to Peak';
            app.VoltagePeaktoPeakEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltagePeaktoPeakEditField.ValueChangedFcn = createCallbackFcn(app, @VoltagePeaktoPeakEditFieldValueChanged, true);
            app.OHMLabel = uilabel(app.UIFigure);
            app.OHMLabel.Text = 'OHM';
            app.uVLabel = uilabel(app.UIFigure);
            app.uVLabel.Text = 'uV';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hwfw

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end