classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        NOTEListBoxLabel        matlab.ui.control.Label
        NOTEListBox             matlab.ui.control.ListBox
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        PLAYButton              matlab.ui.control.Button
        CLEARButton             matlab.ui.control.Button
        SAVEASButton            matlab.ui.control.Button
        FILENAMEEditFieldLabel  matlab.ui.control.Label
        FILENAMEEditField       matlab.ui.control.EditField
        DURATIONButtonGroup     matlab.ui.container.ButtonGroup
        SHORTButton             matlab.ui.control.RadioButton
        MEDIUMButton            matlab.ui.control.RadioButton
        LONGButton              matlab.ui.control.RadioButton
        LOUDNESSButtonGroup     matlab.ui.container.ButtonGroup
        SOFTButton              matlab.ui.control.RadioButton
        MEDIUMButton_2          matlab.ui.control.RadioButton
        LOUDButton              matlab.ui.control.RadioButton
        RECORDButton            matlab.ui.control.StateButton
        SONGMAKERLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        Property % Description
        samplerate=1/20000;
        Soundvar = 0;
        TimeValue=0.4;
        loudness = 2;
        Amplitude=0;
    end
    
    methods (Access = public)
        
        function musical(app, frequency)

           sampletime  = 0:app.samplerate:app.TimeValue;
           soundVector = sin(2*pi*frequency*sampletime);%sine wave selection for the sound 
           fs=500;%frequency shift
           t = 0:1/fs:1-1/fs;%time plot values
           app.Amplitude=(sin(2*pi*frequency*t))* app.loudness;
           fouriersignal=fft(app.Amplitude);
           fouriersignal=fftshift(fouriersignal);
           frequency=fs/2*linspace(-1,1,fs);
           plot(app.UIAxes2,frequency,abs(fouriersignal))
           plot(app.UIAxes,t,app.Amplitude)%plotting the selected sound.
           
           
           sound(soundVector, 1/app.samplerate)
           
           
           CheckRecord = app.RECORDButton.Value;
            %check if the record is stopped or not
            if CheckRecord == 1
                %intitializing every variable 
                SOUNDCOMPOSE=app.Soundvar;
                
                if SOUNDCOMPOSE == 0
                    SOUNDCOMPOSE=soundVector;
               
                else
                    SOUNDCOMPOSE=cat(2,SOUNDCOMPOSE,soundVector);
                end
                app.Soundvar=SOUNDCOMPOSE;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NOTEListBox
        function NOTEListBoxValueChanged(app, event)
            value = app.NOTEListBox.Value;
            switch value
                case 'C'
                    frequency = 130.82
                case 'C#'
                    frequency = 138.59
                case 'D'
                    frequency = 146.83
                case 'D#'
                    frequency = 155.56
                case 'E'
                    frequency = 164.81
                case 'F'
                    frequency = 174.61            
                case 'F#'
                    frequency = 185
                case 'G'
                    frequency = 196
                case 'G#'
                    frequency = 207.65
                case 'A'
                    frequency = 220
                case 'A#'
                    frequency = 233.08
                case 'B'
                    frequency = 246.94
            end
            musical(app, frequency)


        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
                soundvar=app.Soundvar;
                samplerateplay=app.samplerate;
                sound(soundvar,1/samplerateplay);
        end

        % Selection changed function: DURATIONButtonGroup
        function DURATIONButtonGroupSelectionChanged(app, event)
            selectedButton = app.DURATIONButtonGroup.SelectedObject.Text;
            app.TimeValue = 0.4;
            switch(selectedButton)
                case 'SHORT'
                    app.TimeValue=app.TimeValue;
                case 'MEDIUM'
                    app.TimeValue=app.TimeValue*2;
                case 'LONG'
                    app.TimeValue=app.TimeValue*4;
               
            
            end
            
        end

        % Value changed function: RECORDButton
        function RECORDButtonValueChanged(app, event)
            value = app.RECORDButton.Value;
            
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            app.Soundvar = 0
        end

        % Button pushed function: SAVEASButton
        function SAVEASButtonPushed(app, event)
            if app.Soundvar==0
                disp("THERE IS NO NOTE RECORDED YET OR MUST HAVE BEEN RESET")
            else
                AUDIOFILE=app.SONGNAMEFIELD.Value;
                EXT='.wav';
                FILENAME=strcat(AUDIOFILE,EXT);
                DIR='';
                FINALNAME=strcat(DIR,FILENAME);
                audiowrite(FINALNAME,app.Soundvar,1/app.samplerate,'BitsPerSample',32);
            end
        end

        % Selection changed function: LOUDNESSButtonGroup
        function LOUDNESSButtonGroupSelectionChanged(app, event)
            selectedButton = app.LOUDNESSButtonGroup.SelectedObject;
            switch(selectedButton)
                case 'SOFT'
                    app.loudness = app.loudness / 4;
                case 'MEDIUM'
                    app.loudness = app.loudness;
                case 'LONG'
                    app.loudness = app.TimeValue*4;
               
            
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NOTEListBoxLabel = uilabel(app.UIFigure);
            app.NOTEListBoxLabel.Text = 'NOTE ';
            app.NOTEListBox = uilistbox(app.UIFigure);
            app.NOTEListBox.Items = {'C', 'C#', 'D', 'D#', 'E', 'F', 'F#', 'G', 'G#', 'A', 'A#', 'B'};
            app.NOTEListBox.ValueChangedFcn = createCallbackFcn(app, @NOTEListBoxValueChanged, true);
            app.NOTEListBox.Value = 'C';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'FREQUENCY')
            xlabel(app.UIAxes, 'frequency')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'NOTES')
            xlabel(app.UIAxes2, 'Sample')
            ylabel(app.UIAxes2, 'notes')
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.SAVEASButton = uibutton(app.UIFigure, 'push');
            app.SAVEASButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEASButtonPushed, true);
            app.SAVEASButton.Text = 'SAVE AS';
            app.FILENAMEEditFieldLabel = uilabel(app.UIFigure);
            app.FILENAMEEditFieldLabel.Text = 'FILE NAME';
            app.FILENAMEEditField = uieditfield(app.UIFigure, 'text');
            app.DURATIONButtonGroup = uibuttongroup(app.UIFigure);
            app.DURATIONButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DURATIONButtonGroupSelectionChanged, true);
            app.DURATIONButtonGroup.Title = 'DURATION';
            app.SHORTButton = uiradiobutton(app.DURATIONButtonGroup);
            app.SHORTButton.Text = 'SHORT';
            app.SHORTButton.Value = true;
            app.MEDIUMButton = uiradiobutton(app.DURATIONButtonGroup);
            app.MEDIUMButton.Text = 'MEDIUM';
            app.LONGButton = uiradiobutton(app.DURATIONButtonGroup);
            app.LONGButton.Text = 'LONG';
            app.LOUDNESSButtonGroup = uibuttongroup(app.UIFigure);
            app.LOUDNESSButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LOUDNESSButtonGroupSelectionChanged, true);
            app.LOUDNESSButtonGroup.Title = 'LOUDNESS';
            app.SOFTButton = uiradiobutton(app.LOUDNESSButtonGroup);
            app.SOFTButton.Text = 'SOFT';
            app.MEDIUMButton_2 = uiradiobutton(app.LOUDNESSButtonGroup);
            app.MEDIUMButton_2.Text = 'MEDIUM';
            app.MEDIUMButton_2.Value = true;
            app.LOUDButton = uiradiobutton(app.LOUDNESSButtonGroup);
            app.LOUDButton.Text = 'LOUD';
            app.RECORDButton = uibutton(app.UIFigure, 'state');
            app.RECORDButton.ValueChangedFcn = createCallbackFcn(app, @RECORDButtonValueChanged, true);
            app.RECORDButton.Text = 'RECORD';
            app.SONGMAKERLabel = uilabel(app.UIFigure);
            app.SONGMAKERLabel.Text = 'SONG MAKER';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end