classdef PAINO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MUSICCOMPOSINGV16Panel        matlab.ui.container.Panel
        F5Button                      matlab.ui.control.Button
        G5Button                      matlab.ui.control.Button
        A5Button                      matlab.ui.control.Button
        B5Button                      matlab.ui.control.Button
        Asharp5                       matlab.ui.control.Button
        TYPEOFWAVEFORMButtonGroup     matlab.ui.container.ButtonGroup
        SINE                          matlab.ui.control.RadioButton
        SQUARE                        matlab.ui.control.RadioButton
        SAWTOOTH                      matlab.ui.control.RadioButton
        PIANOSIMULATIONINMATLABLabel  matlab.ui.control.Label
        PLAY                          matlab.ui.control.Button
        RESET                         matlab.ui.control.Button
        HELP                          matlab.ui.control.Button
        DURATIONOFNOTEButtonGroup     matlab.ui.container.ButtonGroup
        half                          matlab.ui.control.ToggleButton
        quarter                       matlab.ui.control.ToggleButton
        eight                         matlab.ui.control.ToggleButton
        one                           matlab.ui.control.ToggleButton
        SAVEAS                        matlab.ui.control.Button
        ENTERFILENAMEEditFieldLabel   matlab.ui.control.Label
        SONGNAMEFIELD                 matlab.ui.control.EditField
        E5Button                      matlab.ui.control.Button
        D5Button                      matlab.ui.control.Button
        C5Button                      matlab.ui.control.Button
        B4Button                      matlab.ui.control.Button
        A4Button                      matlab.ui.control.Button
        G4Button                      matlab.ui.control.Button
        F4Button                      matlab.ui.control.Button
        E4Button                      matlab.ui.control.Button
        D4Button                      matlab.ui.control.Button
        C4Button                      matlab.ui.control.Button
        B3Button                      matlab.ui.control.Button
        A3Button                      matlab.ui.control.Button
        G3Button                      matlab.ui.control.Button
        F3Button                      matlab.ui.control.Button
        E3Button                      matlab.ui.control.Button
        D3Button                      matlab.ui.control.Button
        C3Button                      matlab.ui.control.Button
        Gsharp5                       matlab.ui.control.Button
        Fsharp5                       matlab.ui.control.Button
        Dsharp5                       matlab.ui.control.Button
        Csharp5                       matlab.ui.control.Button
        ASharp4                       matlab.ui.control.Button
        Gsharp4                       matlab.ui.control.Button
        Fsharp4                       matlab.ui.control.Button
        Dsharp4                       matlab.ui.control.Button
        Csharp4                       matlab.ui.control.Button
        Asharp3                       matlab.ui.control.Button
        Gsharp3                       matlab.ui.control.Button
        Fsharp3                       matlab.ui.control.Button
        Dsharp3                       matlab.ui.control.Button
        Csharp3                       matlab.ui.control.Button
        RECORD                        matlab.ui.control.StateButton
        Label                         matlab.ui.control.Label
        FREQUENCYLabel                matlab.ui.control.Label
        TIMEEditFieldLabel            matlab.ui.control.Label
        TIMEEditField                 matlab.ui.control.NumericEditField
        BPMLabel                      matlab.ui.control.Label
        INFORMATIONPANELPanel         matlab.ui.container.Panel
        phase                         matlab.ui.control.UIAxes
        waveform                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

   %variables initialization. 
    properties (Access = private)
        
        %Sample rate is the number of samples of audio carried per second.
        samplerate=1/20000;
        %Sound vector variable is used as an array for recording and inserting the frequency one by one as per pressed 
        Soundvector=0;
        %TimeValue is the Value used for the notes time.
        TimeValue=0.3488;
        %Amplitude will be used while plotting the graph
        Amplitude=0;
        
        % Description
        %All this variables are used in the main play fucntion such as to make it easier for other function to access this variable if requied
    end
    
    methods (Access = private)
        
        %can be considered as the main fucntion of the whole program as this function is called when any of the note is selected
        function PLAYNOTE(app,frequency)
          % Converting the frequency to string to display on the main program to let composer know the frequency of the note
            FreqString=num2str(frequency);
            app.Label.Text=FreqString;
            %https://www.mathworks.com/help/signal/ug/edit-time-information-in-the-signal-analyzer-app.html
            %below details can be obtained from the above mentioned websites.
            SampleRate  = app.samplerate;
            app.TimeValue = app.TimeValue;
            TimeSamples  = 0:SampleRate:app.TimeValue;
            %below three variables are based on the selection of the user
            FORSINE=app.SINE.Value;
            FORSQUARE=app.SQUARE.Value;
            FORSAWTOOTH=app.SAWTOOTH.Value;
           
            if FORSINE == 1
                %making tbe use of sin fucntion for the sin wave
                soundVector = sin(2*pi*frequency*TimeSamples);
                 
             elseif FORSQUARE == 1
                %this is for square wave
                 soundVector = square(2*pi*frequency*TimeSamples);
                %this if for sawtooth
            elseif FORSAWTOOTH == 1
                soundVector = sawtooth(2*pi*frequency*TimeSamples);
                
            end
            %this is frequency shift of the signal which is used for time interval of the plot graph 
            fs=500;
             %used for plot the frequency 
            t = 0:1/fs:1-1/fs;
            if FORSINE == 1
                  app.Amplitude=sin(2*pi*frequency*t);
             elseif FORSQUARE == 1
                  app.Amplitude=square(2*pi*frequency*t);
             elseif FORSAWTOOTH == 1
                   app.Amplitude=sawtooth(2*pi*frequency*t);   
            end
                  plot(app.UIAxes,t,app.Amplitude)
                  
                %we are doing fourier transformation
                %to let more information of the signal 
                fouriersignal=fft(app.Amplitude);
                fouriersignal=fftshift(fouriersignal);
                frequency=fs/2*linspace(-1,1,fs);
                plot(app.waveform,frequency,abs(fouriersignal))
            
                
            %checking the noise magnitude
            noise = 2*randn(size(app.Amplitude));
            fftNoise = fft(noise);
            fftNoise = fftshift(fftNoise);
            %plotting the noise magnitude
            plot(app.phase,frequency,abs(fftNoise)) 
             %sound function for generating the sound
            sound(soundVector, 1/SampleRate)
            CheckRecord=app.RECORD.Value;
            %check if the record is stopped or not
            if CheckRecord == 1
                %intitializing every variable 
                SOUNDCOMPOSE=app.Soundvector;
                
                if SOUNDCOMPOSE == 0
                    SOUNDCOMPOSE=soundVector;
               
                else
                    SOUNDCOMPOSE=cat(2,SOUNDCOMPOSE,soundVector);
                end
                app.Soundvector=SOUNDCOMPOSE;
            end
           
           
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Size changed function: MUSICCOMPOSINGV16Panel
        function MUSICCOMPOSINGV16PanelSizeChanged(app, event)
                position = app.MUSICCOMPOSINGV16Panel.Position;
                
        end

        % Button pushed function: PLAY
        function play(app, event)
                Soundvectorplay=app.Soundvector;
                samplerateplay=app.samplerate;
                sound(Soundvectorplay,1/samplerateplay);
        end

        % Button pushed function: RESET
        function reset(app, event)
                app.Soundvector=0;
        end

        % Button pushed function: C3Button
        function C3(app, event)
                frequency=138.81;
                PLAYNOTE(app,frequency);
                
        end

        % Button pushed function: D3Button
        function D3(app, event)
                frequency=146.83;
                PLAYNOTE(app,frequency);
                
        end

        % Button pushed function: E3Button
        function E3(app, event)
                frequency=164.81;
                PLAYNOTE(app,frequency);
        end

        % Callback function
        function size_change(app, event)
                position = app.UIFigure.Position;
                
        end

        % Button pushed function: HELP
        function help_pushed(app, event)
                message = sprintf('Matlab paino version V1.6\nFor any enquiry Contact-\nEmail-abhi83166@gmail.com\nWhatsapp- +601128544017');
                uiwait(msgbox(message));
        end

        % Button pushed function: Csharp3
        function Csharp3ButtonPushed(app, event)
                frequency=138.59;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: F5Button
        function F5ButtonPushed(app, event)
                frequency=698.46;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: G5Button
        function G5ButtonPushed(app, event)
                frequency=783.99;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: A5Button
        function A5ButtonPushed(app, event)
                frequency=880;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: B5Button
        function B5ButtonPushed(app, event)
                frequency=987.77;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: Asharp5
        function Asharp5ButtonPushed(app, event)
                frequency=932.33;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: E5Button
        function E5ButtonPushed(app, event)
                frequency=659.26;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: D5Button
        function D5ButtonPushed(app, event)
                frequency=587.33;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: C5Button
        function C5ButtonPushed(app, event)
                frequency=523.25;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: B4Button
        function B4ButtonPushed(app, event)
                frequency=493.88;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: A4Button
        function A4ButtonPushed(app, event)
                frequency=440;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: G4Button
        function G4ButtonPushed(app, event)
                frequency=392;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: F4Button
        function F4ButtonPushed(app, event)
                frequency=349.23;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: E4Button
        function E4ButtonPushed(app, event)
                frequency=329.63;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: D4Button
        function D4ButtonPushed(app, event)
                frequency=293.66;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: C4Button
        function C4ButtonPushed(app, event)
                frequency=261.625;
                PLAYNOTE(app,frequency);
                
        end

        % Button pushed function: B3Button
        function B3ButtonPushed(app, event)
                frequency=246.94;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: A3Button
        function A3ButtonPushed(app, event)
                frequency=220;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: G3Button
        function G3ButtonPushed(app, event)
                frequency=196;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: F3Button
        function F3ButtonPushed(app, event)
                frequency=174.61;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: Gsharp5
        function Gsharp5ButtonPushed(app, event)
                frequency=830.61;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: Fsharp5
        function Fsharp5ButtonPushed(app, event)
                frequency=739.99;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: Dsharp5
        function Dsharp5ButtonPushed(app, event)
                frequency=622;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: Csharp5
        function Csharp5ButtonPushed(app, event)
                frequency=554.37;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: ASharp4
        function ASharp4ButtonPushed(app, event)
                frequency=466.16;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: Gsharp4
        function Gsharp4ButtonPushed(app, event)
                frequency=415.3;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: Fsharp4
        function Fsharp4ButtonPushed(app, event)
                frequency=369.99;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: Dsharp4
        function Dsharp4ButtonPushed(app, event)
                frequency=311.13;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: Csharp4
        function Csharp4ButtonPushed(app, event)
                frequency=277.18;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: Asharp3
        function Asharp3ButtonPushed(app, event)
                frequency=233.08;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: Gsharp3
        function Gsharp3ButtonPushed(app, event)
                frequency=207.65;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: Fsharp3
        function Fsharp3ButtonPushed(app, event)
                frequency=185.00;
                PLAYNOTE(app,frequency);
        end

        % Button pushed function: Dsharp3
        function Dsharp3ButtonPushed(app, event)
                frequency=155.56;
                PLAYNOTE(app,frequency);
        end

        % Selection changed function: DURATIONOFNOTEButtonGroup
        function DURATIONOFNOTEButtonGroupSelectionChanged(app, event)
                selectedButton = app.DURATIONOFNOTEButtonGroup.SelectedObject.Text;
                  Value=app.TIMEEditField.Value;
                switch (selectedButton)
                    case 'half'
                      app.TimeValue=60/Value*2;
                      disp("My value is half");
                    case 'quarter'
                    app.TimeValue=60/Value;
                      disp("My value is quarter");  
                    case 'eight'   
                      app.TimeValue=(60/Value)/2;
                      disp("My value is eight");
                    case 'one'
 
                      app.TimeValue=60/Value*4;
                      disp("My value is one");
                end    
        end

        % Selection changed function: TYPEOFWAVEFORMButtonGroup
        function TYPEOFWAVEFORMButtonGroupSelectionChanged(app, event)
                selectedButton = app.TYPEOFWAVEFORMButtonGroup.SelectedObject;            
        end

        % Value changed function: RECORD
        function RECORDValueChanged(app, event)
                value = app.RECORD.Value;
                
        end

        % Value changed function: TIMEEditField
        function TIMEEditFieldValueChanged(app, event)
            value = app.TIMEEditField.Value;
            app.TimeValue=60/value;
            selectedButton = app.DURATIONOFNOTEButtonGroup.SelectedObject.Text;
            switch(selectedButton)
                case 'half'
                    app.TimeValue=app.TimeValue*2;
                case 'one'
                    app.TimeValue=app.TimeValue*4;
                case 'eight'
                    app.TimeValue=app.TimeValue/2;
            
            end
            
            
        end

        % Button pushed function: SAVEAS
        function SAVEASButtonPushed(app, event)
            if app.Soundvector==0
                disp("THERE IS NO NOTE RECORDED YET OR MUST HAVE BEEN RESET")
            else
                AUDIOFILE=app.SONGNAMEFIELD.Value;
                EXT='.wav';
                FILENAME=strcat(AUDIOFILE,EXT);
                DIR='G:\ASSIHNMENT 4th sem\MATLAB\ori\';
                FINALNAME=strcat(DIR,FILENAME);
                audiowrite(FINALNAME,app.Soundvector,1/app.samplerate,'BitsPerSample',32);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MUSICCOMPOSINGV16Panel = uipanel(app.UIFigure);
            app.MUSICCOMPOSINGV16Panel.Title = 'MUSIC COMPOSING V1.6';
            app.MUSICCOMPOSINGV16Panel.SizeChangedFcn = createCallbackFcn(app, @MUSICCOMPOSINGV16PanelSizeChanged, true);
            app.F5Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.F5Button.ButtonPushedFcn = createCallbackFcn(app, @F5ButtonPushed, true);
            app.F5Button.Text = 'F5';
            app.G5Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.G5Button.ButtonPushedFcn = createCallbackFcn(app, @G5ButtonPushed, true);
            app.G5Button.Text = 'G5';
            app.A5Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.A5Button.ButtonPushedFcn = createCallbackFcn(app, @A5ButtonPushed, true);
            app.A5Button.Text = 'A5';
            app.B5Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.B5Button.ButtonPushedFcn = createCallbackFcn(app, @B5ButtonPushed, true);
            app.B5Button.Text = 'B5';
            app.Asharp5 = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.Asharp5.ButtonPushedFcn = createCallbackFcn(app, @Asharp5ButtonPushed, true);
            app.Asharp5.Text = 'A#';
            app.TYPEOFWAVEFORMButtonGroup = uibuttongroup(app.MUSICCOMPOSINGV16Panel);
            app.TYPEOFWAVEFORMButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TYPEOFWAVEFORMButtonGroupSelectionChanged, true);
            app.TYPEOFWAVEFORMButtonGroup.Title = 'TYPE OF WAVEFORM';
            app.SINE = uiradiobutton(app.TYPEOFWAVEFORMButtonGroup);
            app.SINE.Text = 'SINE';
            app.SINE.Value = true;
            app.SQUARE = uiradiobutton(app.TYPEOFWAVEFORMButtonGroup);
            app.SQUARE.Text = 'SQUARE';
            app.SAWTOOTH = uiradiobutton(app.TYPEOFWAVEFORMButtonGroup);
            app.SAWTOOTH.Text = 'SAWTOOTH';
            app.PIANOSIMULATIONINMATLABLabel = uilabel(app.MUSICCOMPOSINGV16Panel);
            app.PIANOSIMULATIONINMATLABLabel.Text = 'PIANO SIMULATION IN MATLAB';
            app.PLAY = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.PLAY.ButtonPushedFcn = createCallbackFcn(app, @play, true);
            app.PLAY.Icon = 'Play.png';
            app.PLAY.Text = '';
            app.RESET = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.RESET.ButtonPushedFcn = createCallbackFcn(app, @reset, true);
            app.RESET.Icon = 'reset.jpg';
            app.RESET.Text = '';
            app.HELP = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.HELP.ButtonPushedFcn = createCallbackFcn(app, @help_pushed, true);
            app.HELP.Icon = 'grand_piano.png';
            app.HELP.Text = '';
            app.DURATIONOFNOTEButtonGroup = uibuttongroup(app.MUSICCOMPOSINGV16Panel);
            app.DURATIONOFNOTEButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DURATIONOFNOTEButtonGroupSelectionChanged, true);
            app.DURATIONOFNOTEButtonGroup.Title = 'DURATION OF NOTE';
            app.half = uitogglebutton(app.DURATIONOFNOTEButtonGroup);
            app.half.Text = 'half';
            app.quarter = uitogglebutton(app.DURATIONOFNOTEButtonGroup);
            app.quarter.Text = 'quarter';
            app.quarter.Value = true;
            app.eight = uitogglebutton(app.DURATIONOFNOTEButtonGroup);
            app.eight.Text = 'eight';
            app.one = uitogglebutton(app.DURATIONOFNOTEButtonGroup);
            app.one.Text = 'one';
            app.SAVEAS = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.SAVEAS.ButtonPushedFcn = createCallbackFcn(app, @SAVEASButtonPushed, true);
            app.SAVEAS.Icon = 'Save-as-icon.png';
            app.SAVEAS.Text = 'SAVE AS .wav';
            app.ENTERFILENAMEEditFieldLabel = uilabel(app.MUSICCOMPOSINGV16Panel);
            app.ENTERFILENAMEEditFieldLabel.Text = 'ENTER FILE NAME';
            app.SONGNAMEFIELD = uieditfield(app.MUSICCOMPOSINGV16Panel, 'text');
            app.E5Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.E5Button.ButtonPushedFcn = createCallbackFcn(app, @E5ButtonPushed, true);
            app.E5Button.Text = 'E5';
            app.D5Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.D5Button.ButtonPushedFcn = createCallbackFcn(app, @D5ButtonPushed, true);
            app.D5Button.Text = 'D5';
            app.C5Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.C5Button.ButtonPushedFcn = createCallbackFcn(app, @C5ButtonPushed, true);
            app.C5Button.Text = 'C5';
            app.B4Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.B4Button.ButtonPushedFcn = createCallbackFcn(app, @B4ButtonPushed, true);
            app.B4Button.Text = 'B4';
            app.A4Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.A4Button.ButtonPushedFcn = createCallbackFcn(app, @A4ButtonPushed, true);
            app.A4Button.Text = 'A4';
            app.G4Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.G4Button.ButtonPushedFcn = createCallbackFcn(app, @G4ButtonPushed, true);
            app.G4Button.Text = 'G4';
            app.F4Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.F4Button.ButtonPushedFcn = createCallbackFcn(app, @F4ButtonPushed, true);
            app.F4Button.Text = 'F4';
            app.E4Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.E4Button.ButtonPushedFcn = createCallbackFcn(app, @E4ButtonPushed, true);
            app.E4Button.Text = 'E4';
            app.D4Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.D4Button.ButtonPushedFcn = createCallbackFcn(app, @D4ButtonPushed, true);
            app.D4Button.Text = 'D4';
            app.C4Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.C4Button.ButtonPushedFcn = createCallbackFcn(app, @C4ButtonPushed, true);
            app.C4Button.Text = 'C4';
            app.B3Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.B3Button.ButtonPushedFcn = createCallbackFcn(app, @B3ButtonPushed, true);
            app.B3Button.Text = 'B3';
            app.A3Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.A3Button.ButtonPushedFcn = createCallbackFcn(app, @A3ButtonPushed, true);
            app.A3Button.Text = 'A3';
            app.G3Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.G3Button.ButtonPushedFcn = createCallbackFcn(app, @G3ButtonPushed, true);
            app.G3Button.Text = 'G3';
            app.F3Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.F3Button.ButtonPushedFcn = createCallbackFcn(app, @F3ButtonPushed, true);
            app.F3Button.Text = 'F3';
            app.E3Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.E3Button.ButtonPushedFcn = createCallbackFcn(app, @E3, true);
            app.E3Button.Text = 'E3';
            app.D3Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.D3Button.ButtonPushedFcn = createCallbackFcn(app, @D3, true);
            app.D3Button.Text = 'D3';
            app.C3Button = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.C3Button.ButtonPushedFcn = createCallbackFcn(app, @C3, true);
            app.C3Button.Text = 'C3';
            app.Gsharp5 = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.Gsharp5.ButtonPushedFcn = createCallbackFcn(app, @Gsharp5ButtonPushed, true);
            app.Gsharp5.Text = 'G#';
            app.Fsharp5 = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.Fsharp5.ButtonPushedFcn = createCallbackFcn(app, @Fsharp5ButtonPushed, true);
            app.Fsharp5.Text = 'F#';
            app.Dsharp5 = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.Dsharp5.ButtonPushedFcn = createCallbackFcn(app, @Dsharp5ButtonPushed, true);
            app.Dsharp5.Text = 'D#';
            app.Csharp5 = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.Csharp5.ButtonPushedFcn = createCallbackFcn(app, @Csharp5ButtonPushed, true);
            app.Csharp5.Text = 'C#';
            app.ASharp4 = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.ASharp4.ButtonPushedFcn = createCallbackFcn(app, @ASharp4ButtonPushed, true);
            app.ASharp4.Text = 'A#';
            app.Gsharp4 = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.Gsharp4.ButtonPushedFcn = createCallbackFcn(app, @Gsharp4ButtonPushed, true);
            app.Gsharp4.Text = 'G#';
            app.Fsharp4 = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.Fsharp4.ButtonPushedFcn = createCallbackFcn(app, @Fsharp4ButtonPushed, true);
            app.Fsharp4.Text = 'F#';
            app.Dsharp4 = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.Dsharp4.ButtonPushedFcn = createCallbackFcn(app, @Dsharp4ButtonPushed, true);
            app.Dsharp4.Text = 'D#';
            app.Csharp4 = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.Csharp4.ButtonPushedFcn = createCallbackFcn(app, @Csharp4ButtonPushed, true);
            app.Csharp4.Text = 'C#';
            app.Asharp3 = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.Asharp3.ButtonPushedFcn = createCallbackFcn(app, @Asharp3ButtonPushed, true);
            app.Asharp3.Text = 'A#';
            app.Gsharp3 = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.Gsharp3.ButtonPushedFcn = createCallbackFcn(app, @Gsharp3ButtonPushed, true);
            app.Gsharp3.Text = 'G#';
            app.Fsharp3 = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.Fsharp3.ButtonPushedFcn = createCallbackFcn(app, @Fsharp3ButtonPushed, true);
            app.Fsharp3.Text = 'F#';
            app.Dsharp3 = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.Dsharp3.ButtonPushedFcn = createCallbackFcn(app, @Dsharp3ButtonPushed, true);
            app.Dsharp3.Text = 'D#';
            app.Csharp3 = uibutton(app.MUSICCOMPOSINGV16Panel, 'push');
            app.Csharp3.ButtonPushedFcn = createCallbackFcn(app, @Csharp3ButtonPushed, true);
            app.Csharp3.Text = 'C#';
            app.RECORD = uibutton(app.MUSICCOMPOSINGV16Panel, 'state');
            app.RECORD.ValueChangedFcn = createCallbackFcn(app, @RECORDValueChanged, true);
            app.RECORD.Icon = 'record.png';
            app.RECORD.Text = '';
            app.Label = uilabel(app.MUSICCOMPOSINGV16Panel);
            app.Label.Text = '';
            app.FREQUENCYLabel = uilabel(app.MUSICCOMPOSINGV16Panel);
            app.FREQUENCYLabel.Text = 'FREQUENCY -';
            app.TIMEEditFieldLabel = uilabel(app.MUSICCOMPOSINGV16Panel);
            app.TIMEEditFieldLabel.Text = 'TIME';
            app.TIMEEditField = uieditfield(app.MUSICCOMPOSINGV16Panel, 'numeric');
            app.TIMEEditField.ValueChangedFcn = createCallbackFcn(app, @TIMEEditFieldValueChanged, true);
            app.TIMEEditField.Value = 172;
            app.BPMLabel = uilabel(app.MUSICCOMPOSINGV16Panel);
            app.BPMLabel.Text = 'BPM';
            app.INFORMATIONPANELPanel = uipanel(app.UIFigure);
            app.INFORMATIONPANELPanel.Title = 'INFORMATION PANEL';
            app.phase = uiaxes(app.INFORMATIONPANELPanel);
            title(app.phase, 'NOISE')
            xlabel(app.phase, 'Frequency')
            ylabel(app.phase, 'MAGNITUDE')
            app.waveform = uiaxes(app.INFORMATIONPANELPanel);
            title(app.waveform, 'FOURIER')
            xlabel(app.waveform, 'Times/Sec')
            ylabel(app.waveform, 'FOURIER SIGNAL')
            app.UIAxes = uiaxes(app.INFORMATIONPANELPanel);
            title(app.UIAxes, 'FREQUENCY')
            xlabel(app.UIAxes, 'TIME')
            ylabel(app.UIAxes, 'AMPLITUDE')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PAINO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end