classdef ahmedfyp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        MYSOLARSOLARPANELFORECASTINGANDMONITORINGAPPLabel  matlab.ui.control.Label
        DailyGenerationGaugeLabel      matlab.ui.control.Label
        DailyGenerationGauge           matlab.ui.control.Gauge
        MonthlyGenerationGaugeLabel    matlab.ui.control.Label
        MonthlyGenerationGauge         matlab.ui.control.Gauge
        YearlyGenerationGaugeLabel     matlab.ui.control.Label
        YearlyGenerationGauge          matlab.ui.control.Gauge
        TabGroup                       matlab.ui.container.TabGroup
        CostComparsionTab              matlab.ui.container.Tab
        Image2                         matlab.ui.control.Image
        TodaysSavingLabel              matlab.ui.control.Label
        RMEditFieldLabel               matlab.ui.control.Label
        Ts                             matlab.ui.control.NumericEditField
        Image2_2                       matlab.ui.control.Image
        RMEditField_2Label             matlab.ui.control.Label
        Ms                             matlab.ui.control.NumericEditField
        MonthlySavingLabel             matlab.ui.control.Label
        Image2_3                       matlab.ui.control.Image
        YearlySavingLabel              matlab.ui.control.Label
        RMEditField_3Label             matlab.ui.control.Label
        Ys                             matlab.ui.control.NumericEditField
        ELECTRICITYDEMANDEditFieldLabel  matlab.ui.control.Label
        ELECTRICITYDEMANDEditField     matlab.ui.control.NumericEditField
        StartButton                    matlab.ui.control.Button
        GRIDPAYMENTEditFieldLabel      matlab.ui.control.Label
        GRIDPAYMENTEditField           matlab.ui.control.NumericEditField
        GRIDELECTRICITYEditFieldLabel  matlab.ui.control.Label
        GRIDELECTRICITYEditField       matlab.ui.control.NumericEditField
        RM                             matlab.ui.control.UIAxes
        CO2                            matlab.ui.control.UIAxes
        GRAPHTab                       matlab.ui.container.Tab
        FPG                            matlab.ui.control.UIAxes
        APG                            matlab.ui.control.UIAxes
        FVsAPG                         matlab.ui.control.UIAxes
        PARAMETRSTab                   matlab.ui.container.Tab
        Power                          matlab.ui.control.UIAxes
        Voltage                        matlab.ui.control.UIAxes
        Current                        matlab.ui.control.UIAxes
        Temp                           matlab.ui.control.UIAxes
        Solar                          matlab.ui.control.UIAxes
        CellT                          matlab.ui.control.UIAxes
        GATHEREDDATATab                matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        POWERGENERATIONLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        electricity_demand = 0;
        grid_payment = 0;
        grid_electricity =0;
        power_daily = 0;
        power_monthly = 0;
        power_yearly = 0;
        daily_savings = 0;
        monthly_savings = 0;
        yearly_savings = 0;
        voltage = [12.2500 12.4000 12.8600 13.1900 13.0700 12.6900 12.4000 11.9300 12.0600];
        current = [0.2935000 0.302100 0.3354000 0.355200 0.354600 0.324900 0.295 0.2629000 0.2733000];
        power = [ 3.595050 3.744610 4.314500 4.68610 4.634170 4.122360 3.656610 3.135560 3.295600];
        temp = [27 27 27 27 27 27 27 25 27]
        solar_radiation = [100.7440101 104.9351268 120.9051422 131.2487039 129.8632479 115.5208071 102.4691047 87.86773154 92.35252907];
        cell_temperature = [30.4001100000000	30.5415562500000	31.0805437500000	31.4296200000000	31.3828762500000	30.8988000000000	30.4583287500000	27.9655112500000	30.1168800000000];
        fore_power = [3.721050 3.854610 4.484500 5.002110 4.601170 4.102360 3.566610 3.435560 3.395600]
        pass = 0;
        
        cell_size = cell([40,11]); 
        matlab_data = 1;
        co2 = 0;
        n = 2;% Description
        v
        c
        p
        sp
        t
        ce
        fpg
        apg
        fva
        fva2
        Co2
    end
    
    methods (Access = public)
        
        function main(app)
            app.v = animatedline(app.Voltage,'Color','g','LineWidth',3);
            app.c = animatedline(app.Current,'Color','b','LineWidth',3);
            app.p = animatedline(app.Power,'Color','c','LineWidth',3);
            app.sp = animatedline(app.Solar,'Color','m','LineWidth',3);
            app.t = animatedline(app.Temp,'Color','w','LineWidth',3);
            app.ce = animatedline(app.CellT,'Color','r','LineWidth',3);
            app.fpg= animatedline(app.FPG,'Color','g','LineWidth',3);
            app.apg = animatedline(app.APG,'Color','r','LineWidth',3);
            app.fva = animatedline(app.FVsAPG,'Color','r','LineWidth',3);
            app.fva2 = animatedline(app.FVsAPG,'Color','g','LineWidth',3);
            app.Co2= animatedline(app.CO2,'Color','m','LineWidth',3);
            ax = gca;
            [filename pathname]=uigetfile({'DATA_USED.xlsx'},'File Selector');
            text = readtable(filename, "Sheet",1);
            app.UITable.Data = text;
            startime = datetime('now');
      
            for i=1:9
%             delete(instrfind)
%             s=serial('COM2','BaudRate',115200); %start serial port to arduino
%             fopen(s);
%             pause(2);
%             for i = 1:1000
%              while app.pass<5
%                 data1 = fgets(s);
%                 if(str2double(data1)==535)
%                     app.pass=15;
%                         disp('I m in')
%                  end
%                 app.pass= app.pass+1;
%                  disp('I m out')
%              end
%             fprintf(s,'%i' ,app.matlab_data);
%             data =fgets(s);
%             disp(data)
%             c_str = regexp(data, ',', 'split');
%             app.cell_size{i, 1} = str2double(c_str(1,1));
%             app.cell_size{i, 2} = str2double (c_str(1,2));
%             app.cell_size{i, 3} = str2double (c_str(1,3));
%             app.cell_size{i, 4} = str2double(c_str(1,4));    
%             app. cell_size{i, 5} = str2double(c_str(1,5));
%             app.cell_size{i, 6} = str2double(c_str(1,6));
%             app.cell_size{i, 7} = str2double(c_str(1,7));
%             app.cell_size{i, 8} = str2double(c_str(1,8));
%             app.cell_size{i, 9} = str2double(c_str(1,9));
%             app.cell_size{i, 10} = str2double(c_str(1,10));
%             app.cell_size{i, 11} = str2double(c_str(1,11));
%             app.voltage = cell2mat(app.cell_size(i,1));
%             app.current = cell2mat(app.cell_size(i,2));
%             app.power = cell2mat(app.cell_size(i,3));
%             app.solar_radiation = cell2mat(app.cell_size(i,4));
%             app.cell_temperature = cell2mat(app.cell_size(i,5))  ;  
%             app.power_daily = cell2mat(app.cell_size(i,6));
%             app.power_monthly = cell2mat(app.cell_size(i,7));
%             app.power_yearly = cell2mat(app.cell_size(i,8));
%             app.daily_savings = cell2mat(app.cell_size(i,9));
%             app.monthly_savings = cell2mat(app.cell_size(i,10));
%             app.yearly_savings = cell2mat(app.cell_size(i,11));
%             
%             app.DailyGenerationGauge.Value = app.power_daily;
%             app.MonthlyGenerationGauge.Value = app.power_monthly;
%             app.YearlyGenerationGauge.Value = app.power_yearly;
%             app.Ts.Value = app.daily_savings;
%             app.Ms.Value = app.monthly_savings; 
%             app.Ys.Value = app.yearly_savings;
             ti = datetime('now')-startime;
           
             addpoints(app.v,datenum(ti),app.voltage(1,i))
             addpoints(app.c,datenum(ti),app.current(1,i))
             addpoints(app.p,datenum(ti),app.power(1,i))
             addpoints(app.sp,datenum(ti),app.solar_radiation(1,i))
             addpoints(app.t,datenum(ti),app.temp(1,i))
             addpoints(app.ce,datenum(ti),app.cell_temperature(1,i))
%           
             addpoints(app.apg,datenum(ti),app.power(1,i))
             addpoints(app.fpg,datenum(ti),app.fore_power(1,i))
             addpoints(app.fva,datenum(ti),app.fore_power(1,i))
             addpoints(app.fva2,datenum(ti),app.power(1,i))        
       app.DailyGenerationGauge.Value = 144.867;
       app.MonthlyGenerationGauge.Value = 4168.623; 
       app.Ts.Value = 0.05722;
       app.Ms.Value = 1.6461;
       app.Ys.Value = 0;
       app.co2 = 56.88;
       addpoints(app.Co2,datenum(ti),app.co2)
           
        
             datetick('x','keeplimits')    
             drawnow
             response = thingSpeakWrite(1031488,[app.power(1,i),app.voltage(1,i),app.current(1,i),app.temp(1,i),app.cell_temperature(1,i),app.solar_radiation(1,i)],'WriteKey','HHEVVZUZW0PLSX7N');
             response2 = thingSpeakWrite(1076889,[0.05722,1.6461,56.88],'WriteKey','Q044HD4GGFQ2TJKJ');
             pause(20)
            app.pass=10;
            end
            end
     end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Ts
        function TsValueChanged(app, event)
            value = app.Ts.Value;
         
        end

        % Value changed function: Ms
        function MsValueChanged(app, event)
            value = app.Ms.Value;
      
        end

        % Value changed function: Ys
        function YsValueChanged(app, event)
            value = app.Ys.Value;
          
        end

        % Value changed function: ELECTRICITYDEMANDEditField
        function ELECTRICITYDEMANDEditFieldValueChanged(app, event)
            app.power_monthly = 4168.623;
            app.electricity_demand = app.ELECTRICITYDEMANDEditField.Value;
            app.grid_payment = app.electricity_demand - app.power_monthly * 0.395;
            app.grid_electricity = app.electricity_demand - app.power_monthly;
            app.GRIDELECTRICITYEditField.Value = app.grid_electricity;
            app.GRIDPAYMENTEditField.Value = app.grid_payment;
            histogram(app.RM,app.grid_payment);
%             hold on
%             histogram(app.RM, app.Ms);

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            main(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'sicon3.png';
            app.MYSOLARSOLARPANELFORECASTINGANDMONITORINGAPPLabel = uilabel(app.UIFigure);
            app.MYSOLARSOLARPANELFORECASTINGANDMONITORINGAPPLabel.Text = 'MY SOLAR- SOLAR PANEL FORECASTING AND MONITORING APP';
            app.DailyGenerationGaugeLabel = uilabel(app.UIFigure);
            app.DailyGenerationGaugeLabel.Text = 'Daily Generation';
            app.DailyGenerationGauge = uigauge(app.UIFigure, 'circular');
            app.DailyGenerationGauge.Limits = [0 200];
            app.MonthlyGenerationGaugeLabel = uilabel(app.UIFigure);
            app.MonthlyGenerationGaugeLabel.Text = 'Monthly Generation';
            app.MonthlyGenerationGauge = uigauge(app.UIFigure, 'circular');
            app.MonthlyGenerationGauge.Limits = [0 6000];
            app.YearlyGenerationGaugeLabel = uilabel(app.UIFigure);
            app.YearlyGenerationGaugeLabel.Text = 'Yearly Generation';
            app.YearlyGenerationGauge = uigauge(app.UIFigure, 'circular');
            app.YearlyGenerationGauge.Limits = [0 72000];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CostComparsionTab = uitab(app.TabGroup);
            app.CostComparsionTab.Title = 'Cost Comparsion';
            app.Image2 = uiimage(app.CostComparsionTab);
            app.Image2.ImageSource = 'finance_icon2.jpg.png';
            app.TodaysSavingLabel = uilabel(app.CostComparsionTab);
            app.TodaysSavingLabel.Text = 'Today''s Saving';
            app.RMEditFieldLabel = uilabel(app.CostComparsionTab);
            app.RMEditFieldLabel.Text = 'RM';
            app.Ts = uieditfield(app.CostComparsionTab, 'numeric');
            app.Ts.ValueChangedFcn = createCallbackFcn(app, @TsValueChanged, true);
            app.Image2_2 = uiimage(app.CostComparsionTab);
            app.Image2_2.ImageSource = 'finance_icon2.jpg.png';
            app.RMEditField_2Label = uilabel(app.CostComparsionTab);
            app.RMEditField_2Label.Text = 'RM';
            app.Ms = uieditfield(app.CostComparsionTab, 'numeric');
            app.Ms.ValueChangedFcn = createCallbackFcn(app, @MsValueChanged, true);
            app.MonthlySavingLabel = uilabel(app.CostComparsionTab);
            app.MonthlySavingLabel.Text = 'Monthly Saving';
            app.Image2_3 = uiimage(app.CostComparsionTab);
            app.Image2_3.ImageSource = 'finance_icon2.jpg.png';
            app.YearlySavingLabel = uilabel(app.CostComparsionTab);
            app.YearlySavingLabel.Text = 'Yearly Saving';
            app.RMEditField_3Label = uilabel(app.CostComparsionTab);
            app.RMEditField_3Label.Text = 'RM';
            app.Ys = uieditfield(app.CostComparsionTab, 'numeric');
            app.Ys.ValueChangedFcn = createCallbackFcn(app, @YsValueChanged, true);
            app.ELECTRICITYDEMANDEditFieldLabel = uilabel(app.CostComparsionTab);
            app.ELECTRICITYDEMANDEditFieldLabel.Text = 'ELECTRICITY DEMAND';
            app.ELECTRICITYDEMANDEditField = uieditfield(app.CostComparsionTab, 'numeric');
            app.ELECTRICITYDEMANDEditField.ValueChangedFcn = createCallbackFcn(app, @ELECTRICITYDEMANDEditFieldValueChanged, true);
            app.StartButton = uibutton(app.CostComparsionTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.GRIDPAYMENTEditFieldLabel = uilabel(app.CostComparsionTab);
            app.GRIDPAYMENTEditFieldLabel.Text = 'GRID PAYMENT';
            app.GRIDPAYMENTEditField = uieditfield(app.CostComparsionTab, 'numeric');
            app.GRIDELECTRICITYEditFieldLabel = uilabel(app.CostComparsionTab);
            app.GRIDELECTRICITYEditFieldLabel.Text = 'GRID ELECTRICITY';
            app.GRIDELECTRICITYEditField = uieditfield(app.CostComparsionTab, 'numeric');
            app.RM = uiaxes(app.CostComparsionTab);
            title(app.RM, 'GRID PAYMENT VS MONTHLY SAVING')
            xlabel(app.RM, 'MONTH')
            ylabel(app.RM, 'RM')
            app.CO2 = uiaxes(app.CostComparsionTab);
            title(app.CO2, 'Co2 emission')
            xlabel(app.CO2, 'TIME')
            ylabel(app.CO2, 'Co2 emission(gm)')
            app.GRAPHTab = uitab(app.TabGroup);
            app.GRAPHTab.Title = 'GRAPH';
            app.FPG = uiaxes(app.GRAPHTab);
            title(app.FPG, 'Forcasted Power Generation')
            xlabel(app.FPG, 'TIME')
            ylabel(app.FPG, 'Power')
            app.APG = uiaxes(app.GRAPHTab);
            title(app.APG, 'Actual Power Generation')
            xlabel(app.APG, 'TIME')
            ylabel(app.APG, 'Power')
            app.FVsAPG = uiaxes(app.GRAPHTab);
            title(app.FVsAPG, 'Forcasted vs Actual Power Generation')
            xlabel(app.FVsAPG, 'TIME')
            ylabel(app.FVsAPG, 'Power')
            app.PARAMETRSTab = uitab(app.TabGroup);
            app.PARAMETRSTab.Title = 'PARAMETRS';
            app.Power = uiaxes(app.PARAMETRSTab);
            title(app.Power, 'POWER')
            xlabel(app.Power, 'TIME')
            ylabel(app.Power, 'POWER(W))')
            app.Voltage = uiaxes(app.PARAMETRSTab);
            title(app.Voltage, 'VOLTAGE')
            xlabel(app.Voltage, 'TIME')
            ylabel(app.Voltage, 'VOLTAGE(V)')
            app.Voltage.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Current = uiaxes(app.PARAMETRSTab);
            title(app.Current, 'CURRENT')
            xlabel(app.Current, 'TIME')
            ylabel(app.Current, 'CURRENT(A)')
            app.Temp = uiaxes(app.PARAMETRSTab);
            title(app.Temp, 'TEMPERATURE')
            xlabel(app.Temp, 'TIME')
            ylabel(app.Temp, 'TEMPERATURE(C)')
            app.Solar = uiaxes(app.PARAMETRSTab);
            title(app.Solar, 'SOLAR RADIATION')
            xlabel(app.Solar, 'TIME')
            ylabel(app.Solar, 'SOLAR RADIATION(W/m2)')
            app.CellT = uiaxes(app.PARAMETRSTab);
            title(app.CellT, 'CELL TEMPERATURE')
            xlabel(app.CellT, 'TIME')
            ylabel(app.CellT, 'CELL TEMP(C)')
            app.GATHEREDDATATab = uitab(app.TabGroup);
            app.GATHEREDDATATab.Title = 'GATHERED DATA';
            app.UITable = uitable(app.GATHEREDDATATab);
            app.UITable.ColumnName = {'Temp'; 'Solar Radiation'; 'Power'};
            app.UITable.RowName = {};
            app.POWERGENERATIONLabel = uilabel(app.UIFigure);
            app.POWERGENERATIONLabel.Text = 'POWER GENERATION';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ahmedfyp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end