classdef signal2nd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WAVEFORMTYPESELECTIONDropDownLabel  matlab.ui.control.Label
        WAVEFORMTYPESELECTIONDropDown  matlab.ui.control.DropDown
        AMPLITUDEEditFieldLabel        matlab.ui.control.Label
        AMPLITUDEEditField             matlab.ui.control.NumericEditField
        FREQUENCYEditFieldLabel        matlab.ui.control.Label
        FREQUENCYEditField             matlab.ui.control.NumericEditField
        SIGNALGENERATORLabel           matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        SimulateButton                 matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        Property % Description
        %will contain all the variables required in the program
        waveformselection; simulationbutton; amplitude = 1; frequency = 60;%default values
        sinewavevariations;trainglewavevariations; sawtoothwavevariations;squarewavevariations; %variables for variations
        i=1;
    end
    
    methods (Access = public)
        
        function mainfunction(app)
            
            time = 0:1/1000:(0.25- 1/1000);%time functions graph for 0.255 seconds
            
            sinewave = app.amplitude*(cos(2*pi*app.frequency*time));
            trainglewave = app.amplitude*(sawtooth(2*pi*app.frequency*time, 1/2));
            sawtoothwave = app.amplitude*(sawtooth(2*pi*app.frequency*time));
            squarewave = app.amplitude*(square(2*pi*app.frequency*time));
            
            app.sinewavevariations(app.i,:) = sinewave;
            app.trainglewavevariations(app.i,:)=trainglewave;
            app.sawtoothwavevariations(app.i,:) = sawtoothwave;
            app.squarewavevariations(app.i,:)= squarewave;
           
            if app.simulationbutton == 1
                if strcmpi(app.waveformselection, 'SINE')     
                     plot(app.UIAxes,time,sinewave)
                     for n = 1:app.i
                        plot(app.UIAxes_2,time,app.sinewavevariations)
                     end
                elseif strcmpi(app.waveformselection, 'TRAINGLE')     
             
                     plot(app.UIAxes,time,trainglewave)
                     for n =1:app.i
                        plot(app.UIAxes_2,time,app.trainglewavevariations)
                     end
               elseif strcmpi(app.waveformselection, 'SQUARE')
                     plot(app.UIAxes,time,squarewave)
                     for n = 1:app.i
                        plot(app.UIAxes_2,time,app.squarewavevariations)
                     end
                elseif strcmpi(app.waveformselection, 'SAWTOOTH')
                     plot(app.UIAxes,time,sawtoothwave)
                     for n = 1:app.i
                   
                        plot(app.UIAxes_2,time,app.sawtoothwavevariations)
                     end
                 end 
            end
            app.i= app.i+1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: WAVEFORMTYPESELECTIONDropDown
        function WAVEFORMTYPESELECTIONDropDownValueChanged(app, event)
            app.waveformselection = app.WAVEFORMTYPESELECTIONDropDown.Value;
            mainfunction(app);
        end

        % Value changed function: AMPLITUDEEditField
        function AMPLITUDEEditFieldValueChanged(app, event)
            value = app.AMPLITUDEEditField.Value;
            if value <=0
                   errordlg('Amplitude Should be greater than 0','Invalid Input');
            else
                app.amplitude = value;
                mainfunction(app)
            end  
      
        end

        % Value changed function: FREQUENCYEditField
        function FREQUENCYEditFieldValueChanged(app, event)
            value = app.FREQUENCYEditField.Value;
             if value <=0  
                errordlg('Frequency should be more than 0','Invalid Input');
            else
                app.frequency = value;
                mainfunction(app)
            end
            
      
        end

        % Value changed function: SimulateButton
        function SimulateButtonValueChanged(app, event)
            value = app.SimulateButton.Value;
            if value 
                app.SimulateButton.Text = 'Simulation ON';
                app.simulationbutton = 1;
            else
                app.SimulateButton.Text = 'Simulation OFF'
                app.simulationbutton = 0;
            end
            mainfunction(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WAVEFORMTYPESELECTIONDropDownLabel = uilabel(app.UIFigure);
            app.WAVEFORMTYPESELECTIONDropDownLabel.Text = 'WAVEFORM TYPE SELECTION';
            app.WAVEFORMTYPESELECTIONDropDown = uidropdown(app.UIFigure);
            app.WAVEFORMTYPESELECTIONDropDown.Items = {'SINE', 'SQUARE', 'TRAINGLE', 'SAWTOOTH'};
            app.WAVEFORMTYPESELECTIONDropDown.ValueChangedFcn = createCallbackFcn(app, @WAVEFORMTYPESELECTIONDropDownValueChanged, true);
            app.WAVEFORMTYPESELECTIONDropDown.Value = 'SINE';
            app.AMPLITUDEEditFieldLabel = uilabel(app.UIFigure);
            app.AMPLITUDEEditFieldLabel.Text = 'AMPLITUDE';
            app.AMPLITUDEEditField = uieditfield(app.UIFigure, 'numeric');
            app.AMPLITUDEEditField.ValueChangedFcn = createCallbackFcn(app, @AMPLITUDEEditFieldValueChanged, true);
            app.FREQUENCYEditFieldLabel = uilabel(app.UIFigure);
            app.FREQUENCYEditFieldLabel.Text = 'FREQUENCY';
            app.FREQUENCYEditField = uieditfield(app.UIFigure, 'numeric');
            app.FREQUENCYEditField.ValueChangedFcn = createCallbackFcn(app, @FREQUENCYEditFieldValueChanged, true);
            app.SIGNALGENERATORLabel = uilabel(app.UIFigure);
            app.SIGNALGENERATORLabel.Text = 'SIGNAL GENERATOR';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cuurent Waveform')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Variation Waveform')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.SimulateButton = uibutton(app.UIFigure, 'state');
            app.SimulateButton.ValueChangedFcn = createCallbackFcn(app, @SimulateButtonValueChanged, true);
            app.SimulateButton.Text = 'Simulate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal2nd

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end