classdef signalgeneratorv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        INPUTTab                       matlab.ui.container.Tab
        WAVEFORMDropDownLabel          matlab.ui.control.Label
        WAVEFORMDropDown               matlab.ui.control.DropDown
        FrequencyEditFieldLabel        matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel        matlab.ui.control.Label
        AmplitudeEditField             matlab.ui.control.NumericEditField
        NumberofsamplesSliderLabel     matlab.ui.control.Label
        NumberofsamplesSlider          matlab.ui.control.Slider
        StopTimesecondsEditFieldLabel  matlab.ui.control.Label
        StopTimesecondsEditField       matlab.ui.control.NumericEditField
        SimulateButton                 matlab.ui.control.StateButton
        SIGNALGENERATORLabel           matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Image_2                        matlab.ui.control.Image
        Image_3                        matlab.ui.control.Image
        Image_4                        matlab.ui.control.Image
        SawtoothCircuitLabel           matlab.ui.control.Label
        TraingleCircuitLabel           matlab.ui.control.Label
        SquareCircuitLabel             matlab.ui.control.Label
        SineCircuitLabel               matlab.ui.control.Label
        SimulateAllButton              matlab.ui.control.StateButton
        SINETab                        matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        SQUARETab                      matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes2_2                      matlab.ui.control.UIAxes
        TRAINGLETab                    matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes3_3                      matlab.ui.control.UIAxes
        SAWTOOTHTab                    matlab.ui.container.Tab
        UIAxes3_2                      matlab.ui.control.UIAxes
        UIAxes3_4                      matlab.ui.control.UIAxes
        ALLTab                         matlab.ui.container.Tab
        UIAxes5                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %included all the variables required for the users 
        Property % Description
        %below four variables hold the value of four wavetype
        sawtooth = 0;
        traingle = 0;
        sine = 0;
        square = 0;
        set = 0;
        frequency = 50;%frequency variable for signal generator
        amplitude = 5;%frequency variable for signal generator
        nofsamples = 1000;%number of samples variable for signal generator
        stoptime = 0.25;%for number of time the simulation will be performed
        buttonstate = 0;%hold the simulation state
        waveformtype = 'SINE' ; %waveform state variable from the drop down
        sinetemp=0 ; % holding temporary value of the sine for variations
        traingletemp = 0 ; % holding temporary value of the traingle for variations
        sawtoothtemp= 0 ; % holding temporary value of the sawtooth for variations
        sqauretemp = 0 ; % % holding temporary value of the square for variations
        iterationsine=0; % holding iteration value of the sine for variations
        iterationtraingle=0; % holding iterations value of the traingle for variations
        iterationsquare=0; % holding iterations value of the square for variation
        iterationsawtooth=0; % holding iterations value of the sawtooth for variation
        buttonstateall = 0; %holding button state for all simulation tab
    end
    
    methods (Access = public)
        
        function waveform(app)%function for simulation button
            %unless the button is in on state executions wont work
            if app.buttonstate == 1
                    dt = 1/app.nofsamples;%derivate of time
                    t= (0:dt:app.stoptime-dt)';%time function for the graph
                
                if strcmpi(app.waveformtype, 'SINE') %drop down variable for sine
                    if(app.iterationsine ~=0)%checking whether the first iteration is performed or not
                        app.sinetemp = app.sine;% assign the previous sine value to temporary value
                    end
                    app.sine = app.amplitude*(cos(2*pi*app.frequency*t));% calcution for sine values based
                    %on the formula
                    %below lines plot the values on all the differnt axis
                    %required and hold the plot for variation comparisons
                    plot(app.UIAxes,t,app.sine)
                    plot(app.UIAxes_2,t,app.sine)
                    hold(app.UIAxes_2,'on')
                    plot(app.UIAxes_2,t,app.sinetemp)
                    app.iterationsine = 1;%this variable will make sure that the next execution onwards
                    %the temporary vriable will store the previous value
             
             
             %all the other waveform executions are similar to the sine
             %while the difference being the formula and calculation for
             %different waveform
                elseif strcmpi(app.waveformtype, 'TRAINGLE')
                     if(app.iterationtraingle ~=0)
                        app.traingletemp = app.traingle;
                     end
                    app.traingle = app.amplitude*sawtooth(2*pi*app.frequency*t, 1/2);% the different between
                    %sawtooth and traingle wave is traingle wave is half of
                    %the sawtooth wave
                    plot(app.UIAxes3,t,app.traingle)
                    plot(app.UIAxes3_3,t,app.traingle)
                    hold(app.UIAxes3_3,'on')
                    plot(app.UIAxes3_3,t,app.traingletemp)
                    app.iterationtraingle = 1;
               
                elseif strcmpi(app.waveformtype, 'SQUARE')
                    if(app.iterationsquare ~=0)
                        app.sqauretemp = app.square;
                    end
                    
                    app.square = app.amplitude*square(2*pi*app.frequency*t);
                    plot(app.UIAxes2,t, app.square);
                    plot(app.UIAxes2_2,t,app.square)
                    hold(app.UIAxes2_2,'on')
                    plot(app.UIAxes2_2,t,app.sqauretemp)
                    
                    app.iterationsquare = 1;
                
                     
                elseif strcmpi(app.waveformtype,'SAWTOOTH')
                   if(app.iterationsawtooth ~= 0)
                       app.sawtoothtemp = app.sawtooth;
                       
                   end
                    app.sawtooth = app.amplitude*sawtooth(2*pi*app.frequency*t);
                    
                    plot(app.UIAxes3_2,t, app.sawtooth)
                    plot(app.UIAxes3_4,t,app.sawtooth)
                    hold(app.UIAxes3_4,'on')
                    plot(app.UIAxes3_4, t,app.sawtoothtemp)                    
                    app.iterationsawtooth = 1;
                
                
                end%end of drop down comparison algo


                else
                
            
            end%end of button state algo
         
        end%end of wavefunction
            
        %below function is for the simulation all button this works
        %similar to the previous function while difference being this
        %function plots all the waveform at once.
            function waveformall(app)
                
              if app.buttonstateall == 1
                    dt = 1/app.nofsamples;
                    t= (0:dt:app.stoptime-dt)';
                    app.sine = app.amplitude*(cos(2*pi*app.frequency*t));
                    app.square = app.amplitude*square(2*pi*app.frequency*t);
                    app.traingle = app.amplitude*sawtooth(2*pi*app.frequency*t, 1/2);
                    app.sawtooth = app.amplitude*sawtooth(2*pi*app.frequency*t);
                    
                    plot(app.UIAxes5,t,app.sine)
                    hold(app.UIAxes5,'on')
                    plot(app.UIAxes5,t,app.traingle)
                    plot(app.UIAxes5,t,app.square)
                    plot(app.UIAxes5,t,app.sawtooth)
                    
                     hold(app.UIAxes5,'off')
                    
              end  
            end
            
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: WAVEFORMDropDown
        function WAVEFORMDropDownValueChanged(app, event)
          app.waveformtype  = app.WAVEFORMDropDown.Value;
          disp(app.waveformtype)
          waveform(app)
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
              value = app.FrequencyEditField.Value;
            %error check routine, if user's input is invalid it will
            %no value will be initialized to the waveform function
              if value <= 0
                errordlg('Enter a positive value','Input error');
            else
                app.frequency = value;
           
            end
            
            waveform(app)
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
           value = app.AmplitudeEditField.Value;
            if value <= 0
                errordlg('Enter a Positive Value','Input error');
            else
                app.amplitude = value;
           
            end
            
            waveform(app)
        end

        % Value changed function: NumberofsamplesSlider
        function NumberofsamplesSliderValueChanged(app, event)
           app.nofsamples = app.NumberofsamplesSlider.Value;
            waveform(app)
        end

        % Value changed function: StopTimesecondsEditField
        function StopTimesecondsEditFieldValueChanged(app, event)
            value = app.StopTimesecondsEditField.Value;
            if value <= 0
                errordlg('Enter a Positive Value','Input error');
            else
                app.stoptime = value;
            end
            app.StopTimesecondsEditField.Visible = 'off'
            app.StopTimesecondsEditFieldLabel.Visible ='off'
            waveform(app)
        end

        % Value changed function: SimulateButton
        function SimulateButtonValueChanged(app, event)
            app.buttonstate = app.SimulateButton.Value;
            waveform(app)
        end

        % Value changed function: SimulateAllButton
        function SimulateAllButtonValueChanged(app, event)
            app.buttonstateall = app.SimulateAllButton.Value;
            waveformall(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.INPUTTab = uitab(app.TabGroup);
            app.INPUTTab.Title = 'INPUT';
            app.WAVEFORMDropDownLabel = uilabel(app.INPUTTab);
            app.WAVEFORMDropDownLabel.Text = 'WAVEFORM';
            app.WAVEFORMDropDown = uidropdown(app.INPUTTab);
            app.WAVEFORMDropDown.Items = {'SINE', 'SQUARE', 'TRAINGLE', 'SAWTOOTH'};
            app.WAVEFORMDropDown.ValueChangedFcn = createCallbackFcn(app, @WAVEFORMDropDownValueChanged, true);
            app.WAVEFORMDropDown.Value = 'SINE';
            app.FrequencyEditFieldLabel = uilabel(app.INPUTTab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.INPUTTab, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.AmplitudeEditFieldLabel = uilabel(app.INPUTTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.INPUTTab, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.NumberofsamplesSliderLabel = uilabel(app.INPUTTab);
            app.NumberofsamplesSliderLabel.Text = 'Number of samples';
            app.NumberofsamplesSlider = uislider(app.INPUTTab);
            app.NumberofsamplesSlider.Limits = [1000 10000];
            app.NumberofsamplesSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofsamplesSliderValueChanged, true);
            app.NumberofsamplesSlider.Value = 1000;
            app.StopTimesecondsEditFieldLabel = uilabel(app.INPUTTab);
            app.StopTimesecondsEditFieldLabel.Text = 'Stop Time(seconds)';
            app.StopTimesecondsEditField = uieditfield(app.INPUTTab, 'numeric');
            app.StopTimesecondsEditField.ValueChangedFcn = createCallbackFcn(app, @StopTimesecondsEditFieldValueChanged, true);
            app.SimulateButton = uibutton(app.INPUTTab, 'state');
            app.SimulateButton.ValueChangedFcn = createCallbackFcn(app, @SimulateButtonValueChanged, true);
            app.SimulateButton.Text = 'Simulate';
            app.SIGNALGENERATORLabel = uilabel(app.INPUTTab);
            app.SIGNALGENERATORLabel.Text = 'SIGNAL GENERATOR';
            app.Image = uiimage(app.INPUTTab);
            app.Image.ImageSource = 'SINECIRCUIT.jpg';
            app.Image_2 = uiimage(app.INPUTTab);
            app.Image_2.ImageSource = 'SQUARE CIRCUIT.png';
            app.Image_3 = uiimage(app.INPUTTab);
            app.Image_3.ImageSource = 'SQUARE_CIRCUIT.jpg';
            app.Image_4 = uiimage(app.INPUTTab);
            app.Image_4.ImageSource = 'SAWTOOTHCIRCUIT.jpg';
            app.SawtoothCircuitLabel = uilabel(app.INPUTTab);
            app.SawtoothCircuitLabel.Text = 'Sawtooth Circuit';
            app.TraingleCircuitLabel = uilabel(app.INPUTTab);
            app.TraingleCircuitLabel.Text = 'Traingle Circuit';
            app.SquareCircuitLabel = uilabel(app.INPUTTab);
            app.SquareCircuitLabel.Text = 'Square Circuit';
            app.SineCircuitLabel = uilabel(app.INPUTTab);
            app.SineCircuitLabel.Text = 'Sine Circuit';
            app.SimulateAllButton = uibutton(app.INPUTTab, 'state');
            app.SimulateAllButton.ValueChangedFcn = createCallbackFcn(app, @SimulateAllButtonValueChanged, true);
            app.SimulateAllButton.Text = 'Simulate All';
            app.SINETab = uitab(app.TabGroup);
            app.SINETab.Title = 'SINE';
            app.UIAxes = uiaxes(app.SINETab);
            title(app.UIAxes, 'SINE')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.SINETab);
            title(app.UIAxes_2, 'SINE VARIATIONS')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.SQUARETab = uitab(app.TabGroup);
            app.SQUARETab.Title = 'SQUARE';
            app.UIAxes2 = uiaxes(app.SQUARETab);
            title(app.UIAxes2, 'SQUARE')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2_2 = uiaxes(app.SQUARETab);
            title(app.UIAxes2_2, 'SQUARE VARIATIONS')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.TRAINGLETab = uitab(app.TabGroup);
            app.TRAINGLETab.Title = 'TRAINGLE';
            app.UIAxes3 = uiaxes(app.TRAINGLETab);
            title(app.UIAxes3, 'TRAINGLE')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3_3 = uiaxes(app.TRAINGLETab);
            title(app.UIAxes3_3, 'TRAINGLE VARIATIONS')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            app.SAWTOOTHTab = uitab(app.TabGroup);
            app.SAWTOOTHTab.Title = 'SAWTOOTH';
            app.UIAxes3_2 = uiaxes(app.SAWTOOTHTab);
            title(app.UIAxes3_2, 'SAWTOOTH')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            app.UIAxes3_4 = uiaxes(app.SAWTOOTHTab);
            title(app.UIAxes3_4, 'SAWTOOTH VARIATIONS')
            xlabel(app.UIAxes3_4, 'X')
            ylabel(app.UIAxes3_4, 'Y')
            app.ALLTab = uitab(app.TabGroup);
            app.ALLTab.Title = 'ALL';
            app.UIAxes5 = uiaxes(app.ALLTab);
            title(app.UIAxes5, 'ALL')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signalgeneratorv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end